classdef slrt_ex_pmsm_SLRTInstrumentPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIFigureGrid             matlab.ui.container.GridLayout
        ToolstripPanel           matlab.ui.container.Panel
        SystemLogPanel           matlab.ui.container.Panel
        SystemLogGrid            matlab.ui.container.GridLayout
        SystemLog                slrealtime.ui.control.SystemLog
        RunOnTarget              matlab.ui.container.Panel
        RunOnTargetGrid          matlab.ui.container.GridLayout
        StopTimeLabel            matlab.ui.control.Label
        StartStopButton          slrealtime.ui.control.StartStopButton
        StopTimeEditField        slrealtime.ui.control.StopTimeEditField
        Prepare                  matlab.ui.container.Panel
        PrepareGrid              matlab.ui.container.GridLayout
        LoadButton               slrealtime.ui.control.LoadButton
        ConnectToTarget          matlab.ui.container.Panel
        ConnectToTargetGrid      matlab.ui.container.GridLayout
        TargetSelector           slrealtime.ui.control.TargetSelector
        ConnectButton            slrealtime.ui.control.ConnectButton
        TabGroup                 matlab.ui.container.TabGroup
        AllComponentsTab         matlab.ui.container.Tab
        AllComponentsGridLayout  matlab.ui.container.GridLayout
        GridLayout_2             matlab.ui.container.GridLayout
        LdKnob                   matlab.ui.control.Knob
        LdKnobLabel              matlab.ui.control.Label
        LqKnob                   matlab.ui.control.Knob
        LqKnobLabel              matlab.ui.control.Label
        GridLayout               matlab.ui.container.GridLayout
        WrefPUKnob               matlab.ui.control.Knob
        WrefPUKnobLabel          matlab.ui.control.Label
        GatesInputSwitch         matlab.ui.control.ToggleSwitch
        GatesInputSwitchLabel    matlab.ui.control.Label
        SignalTable              slrealtime.ui.control.SignalTable
        InstrumentedSignalsAxes  matlab.ui.control.UIAxes
        StatusBarPanel           matlab.ui.container.Panel
        StatusBarGrid            matlab.ui.container.GridLayout
        StatusBar                slrealtime.ui.control.StatusBar
    end

    
    methods (Static, Access = private)
        function out_val = convToDouble(in_val)
            if isnumeric(in_val)
                out_val = double(in_val);
            else
                out_val = str2double(in_val);
            end
        end
        
        function out_val = convToString(in_val)
            if isnumeric(in_val)
                out_val = string(num2str(in_val));
            else
                out_val = string(in_val);
            end
        end
        
        function out_val = convToScalarLogical(in_val)
            if isnumeric(in_val)
                out_val = any(logical(in_val));
            else
                out_val = any(logical(str2double(in_val)));
            end
        end

        function out_val = convertLabelToValue(in_val, values, labels)
            out_val = values(find(strcmp(in_val, labels))); %#ok
        end

        function out_val = convertValueToLabel(in_val, values, labels)
            out_val = labels(find(arrayfun(@(x)isequal(in_val, x), values))); %#ok
        end

        function out_data = displayBlockCB(in_data)
            if isnumeric(in_data)
                out_data = string(num2str(in_data));
            else
                out_data = string(in_data);
            end
        end
        
        function out_val = lampBlockCB(in_val, states, default)
            values = states{1};
            colors = states{2};
            
            in_val = in_val(end);
            
            useDefault = true;
            for i=1:length(values)
                if in_val == values(i)
                    out_val = colors(i,:);
                    useDefault = false;
                    break;
                end
            end
            if useDefault
                out_val = default;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            targetSelector = app.TargetSelector;

            app.ConnectButton.TargetSource = targetSelector;
            app.LoadButton.TargetSource = targetSelector;
            app.StartStopButton.TargetSource = targetSelector;
            app.StopTimeEditField.TargetSource = targetSelector;
            app.SystemLog.TargetSource = targetSelector;
            app.StatusBar.TargetSource = targetSelector;

            % Create and configure axis object
            hInst = slrealtime.Instrument();
            %hInst.connectLine(app.InstrumentedSignalsAxes, {'Ia1'}, 1);
            hInst.connectLine(app.InstrumentedSignalsAxes, {'slrt_ex_pmsm/Ib'}, 1);
            hInst.connectLine(app.InstrumentedSignalsAxes, {'slrt_ex_pmsm/Ic'}, 1);
            legend(app.InstrumentedSignalsAxes);

            hInst.AxesTimeSpan = 10;
            hInst.AxesTimeSpanOverrun = 'scroll';

            % Create InstrumentManager object and add instruments
            slrtInstrumentsComponent = slrealtime.ui.tool.InstrumentManager(app.UIFigure, 'TargetSource', targetSelector);
            slrtInstrumentsComponent.Instruments = hInst;

            % Configure Signal Table
             app.SignalTable.Signals = struct( ...
                 'BlockPath', {'slrt_ex_pmsm/wMotor', 'slrt_ex_pmsm/thMotor1', }, ...
                 'PortIndex', {1, 1});

            % Set up parameter tuner
            gatesInput = slrealtime.ui.tool.ParameterTuner(app.UIFigure);
            gatesInput.Component = app.GatesInputSwitch;
            gatesInput.BlockPath = '';
            gatesInput.ParameterName = 'gatesInputFlag';

            vdcInput = slrealtime.ui.tool.ParameterTuner(app.UIFigure);
            vdcInput.Component = app.WrefPUKnob;
            vdcInput.BlockPath = '';
            vdcInput.ParameterName = 'PUSpeedRef';

            vdcInput2 = slrealtime.ui.tool.ParameterTuner(app.UIFigure);
            vdcInput2.Component = app.LdKnob;
            vdcInput2.BlockPath = '';
            vdcInput2.ParameterName = 'Ld';

            vdcInput3 = slrealtime.ui.tool.ParameterTuner(app.UIFigure);
            vdcInput3.Component = app.LdKnob;
            vdcInput3.BlockPath = '';
            vdcInput3.ParameterName = 'Lq';

            % Set up parameter table
%             app.ParameterTable.Parameters = struct(...
%                 'BlockPath', {''},...
%                 'ParameterName', {'N'});


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigureGrid = uigridlayout(app.UIFigure);
            app.StatusBarPanel = uipanel(app.UIFigureGrid);
            app.StatusBarGrid = uigridlayout(app.StatusBarPanel);
            app.StatusBar = slrealtime.ui.control.StatusBar(app.StatusBarGrid);
            app.TabGroup = uitabgroup(app.UIFigureGrid);
            app.AllComponentsTab = uitab(app.TabGroup);
            app.AllComponentsTab.Title = 'Signals and Parameters';
            app.AllComponentsGridLayout = uigridlayout(app.AllComponentsTab);
            app.InstrumentedSignalsAxes = uiaxes(app.AllComponentsGridLayout);
            title(app.InstrumentedSignalsAxes, 'Instrumented Signals')
            xlabel(app.InstrumentedSignalsAxes, 'time (seconds)')
            app.SignalTable = slrealtime.ui.control.SignalTable(app.AllComponentsGridLayout);
            app.GridLayout = uigridlayout(app.AllComponentsGridLayout);
            app.GatesInputSwitchLabel = uilabel(app.GridLayout);
            app.GatesInputSwitchLabel.Text = 'Gates Input';
            app.GatesInputSwitch = uiswitch(app.GridLayout, 'toggle');
            app.GatesInputSwitch.Items = {'Internal', 'External'};
            app.GatesInputSwitch.ItemsData = [1 0];
            app.GatesInputSwitch.Value = 1;
            app.WrefPUKnobLabel = uilabel(app.GridLayout);
            app.WrefPUKnobLabel.Text = 'WrefPU';
            app.WrefPUKnob = uiknob(app.GridLayout, 'continuous');
            app.WrefPUKnob.Limits = [0 1];
            app.GridLayout_2 = uigridlayout(app.AllComponentsGridLayout);
            app.LqKnobLabel = uilabel(app.GridLayout_2);
            app.LqKnobLabel.Text = 'Lq';
            app.LqKnob = uiknob(app.GridLayout_2, 'continuous');
            app.LqKnob.Limits = [0 0.001];
            app.LdKnobLabel = uilabel(app.GridLayout_2);
            app.LdKnobLabel.Text = 'Ld';
            app.LdKnob = uiknob(app.GridLayout_2, 'continuous');
            app.LdKnob.Limits = [0 0.001];
            app.ToolstripPanel = uipanel(app.UIFigureGrid);
            app.ConnectToTarget = uipanel(app.ToolstripPanel);
            app.ConnectToTargetGrid = uigridlayout(app.ConnectToTarget);
            app.ConnectButton = slrealtime.ui.control.ConnectButton(app.ConnectToTargetGrid);
            app.TargetSelector = slrealtime.ui.control.TargetSelector(app.ConnectToTargetGrid);
            app.Prepare = uipanel(app.ToolstripPanel);
            app.PrepareGrid = uigridlayout(app.Prepare);
            app.LoadButton = slrealtime.ui.control.LoadButton(app.PrepareGrid);
            app.LoadButton.ShowLoadedApplication = false;
            app.RunOnTarget = uipanel(app.ToolstripPanel);
            app.RunOnTargetGrid = uigridlayout(app.RunOnTarget);
            app.StopTimeEditField = slrealtime.ui.control.StopTimeEditField(app.RunOnTargetGrid);
            app.StartStopButton = slrealtime.ui.control.StartStopButton(app.RunOnTargetGrid);
            app.StopTimeLabel = uilabel(app.RunOnTargetGrid);
            app.StopTimeLabel.Text = 'Stop Time';
            app.SystemLogPanel = uipanel(app.ToolstripPanel);
            app.SystemLogGrid = uigridlayout(app.SystemLogPanel);
            app.SystemLog = slrealtime.ui.control.SystemLog(app.SystemLogGrid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = slrt_ex_pmsm_SLRTInstrumentPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end